classdef sensor18reach < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        UITable                   matlab.ui.control.Table
        UIAxes                    matlab.ui.control.UIAxes
        ClearMapButton            matlab.ui.control.Button
        PlotLocal                 matlab.ui.control.Button
        LoadLocalDataButton       matlab.ui.control.Button
        ExportCSVFileButton       matlab.ui.control.Button
        RedSlowingDownLabel       matlab.ui.control.Label
        GreenSpeedingUpLabel      matlab.ui.control.Label
        PurpleSteadySpeedLabel    matlab.ui.control.Label
        LoadThingSpeakDataButton  matlab.ui.control.Button
        DistancemEditFieldLabel   matlab.ui.control.Label
        DistancemEditField        matlab.ui.control.NumericEditField
        UploadLocal               matlab.ui.control.Button
        CheckLoad                 matlab.ui.control.Label
        PlotCalcLocalTS           matlab.ui.control.Button
        WriteKey                  matlab.ui.control.EditField
        InsertThingSpeakChannelIDtoReadEditFieldLabel  matlab.ui.control.Label
        ChannelRead               matlab.ui.control.NumericEditField
        ChannelWrite              matlab.ui.control.EditField
        BlueLocalPointLabel       matlab.ui.control.Label
        PinkTSPointLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        data % Description
        data_table
        TS_table
        local_lastX
        local_lastY
        TS_lastX
        TS_lastY
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadLocalDataButton
        function LoadLocalDataButtonPushed(app, event)
            [file,path] =uigetfile({'*.mat;'},'Select File to Open');      
            if isequal(file,0)
                disp('No file selected')
                app.CheckLoad.Visible = 'on';
                app.CheckLoad.Text = 'No file selected';
            else
                app.data  = load(fullfile(path,file));
                T = app.data.Position.Timestamp;
                GPSX = app.data.Position.longitude;
                GPSY = app.data.Position.latitude;
                AccelerationX = app.data.Acceleration.X;
                AccelerationY = app.data.Acceleration.Y;
                app.data_table = table(T, GPSX, GPSY, AccelerationX, AccelerationY);
                app.UITable.Data = app.data_table;
                app.CheckLoad.Visible = 'on';
                app.CheckLoad.Text = 'File Loaded';

            end


        end

        % Button pushed function: PlotLocal
        function PlotLocalPushed(app, event)
             cla(app.UIAxes);
            figure();set(gcf,'Visible', 'off');
            app.CheckLoad.Visible = 'off';

            xvalues = app.data.Position.longitude;
            yvalues = app.data.Position.latitude;
            max_x = max(xvalues);
            min_x = min(xvalues);
            max_y = max(yvalues);
            min_y = min(yvalues);
            
            x_range = max_x - min_x;
            y_range = max_y - min_y;
            min_x = min_x - 0.05 * x_range;
            min_y = min_y - 0.05 * y_range;
            max_x = max_x + 0.05 * x_range;
            max_y = max_y + 0.05 * y_range;
            xlim(app.UIAxes, [min_x,max_x])
            ylim(app.UIAxes, [min_y,max_y])
    
            scatter(app.UIAxes, xvalues(1), yvalues(1), 36, "red", "filled");

            hold(app.UIAxes, "on")
            if max(app.data.Acceleration.X) > max(app.data.Acceleration.Y)
                acceleration = app.data.Acceleration.X;
            else
                acceleration = app.data.Acceleration.Y;
            end
            for k = 1: 15: length(xvalues)
                hold on
                if acceleration(k) < -1.5
                    color = "red";
                else 
                    if acceleration(k) > 1.5
                        color = "green";
                    else
                        color = "blue";
                    end
                end
                
                scatter(app.UIAxes, xvalues(k), yvalues(k), 36, color, "filled");
            
                pause(0.1)
            end

        end

        % Button pushed function: ExportCSVFileButton
        function ExportCSVFileButtonPushed(app, event)
            [file,path]=uiputfile('data_table.csv');
            writetable(app.data_table,[path,file]);
            
        end

        % Button pushed function: ClearMapButton
        function ClearMapButtonPushed(app, event)
            cla(app.UIAxes);
        end

        % Button pushed function: UploadLocal
        function UploadLocalButtonPushed(app, event)
            thingSpeakWrite(str2double(app.ChannelWrite.Value),app.data_table,'WriteKey',string(app.WriteKey.Value));
        end

        % Button pushed function: LoadThingSpeakDataButton
        function LoadThingSpeakDataButtonPushed(app, event)
            app.TS_table=thingSpeakRead(app.ChannelRead.Value,'OutputFormat','Timetable');
        end

        % Button pushed function: PlotCalcLocalTS
        function PlotCalcLocalTSButtonPushed(app, event)
            cla(app.UIAxes);
            hold(app.UIAxes, "on");
            app.local_lastX=app.UITable.Data.GPSX(end);
            app.local_lastY=app.UITable.Data.GPSY(end);
            app.TS_lastX=app.TS_table.GPSX(1);
            app.TS_lastY=app.TS_table.GPSY(1);
            
            scatter(app.UIAxes,app.local_lastX , app.local_lastY,50,"blue","filled"); %plot local
            scatter(app.UIAxes, app.TS_lastX,app.TS_lastY,50,"magenta","filled"); %plot TS
            plot(app.UIAxes,[app.local_lastX,app.TS_lastX],[app.local_lastY,app.TS_lastY]); %connect line
            
            %calculate distance
           y1=app.local_lastY*(pi/180);
           y2=app.TS_lastY*(pi/180);
           dy=(y2-y1);
           dx=(app.TS_lastX-app.local_lastX)*(pi/180);
           R=6371000;
           
           a=(sin(dy/2).^2)+cos(y1).*cos(y2).*(sin(dx/2).^2);
           c=2.*atan2(sqrt(a),sqrt(1-a));
           
           app.DistancemEditField.Value=R.*c;
            
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Time'; 'GPSX'; 'GPSY'; 'AccelerationX'; 'Acceleration Y'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'User Map')
            xlabel(app.UIAxes, 'X (Longitude)')
            ylabel(app.UIAxes, 'Y (Latitude)')
            app.ClearMapButton = uibutton(app.GridLayout, 'push');
            app.ClearMapButton.ButtonPushedFcn = createCallbackFcn(app, @ClearMapButtonPushed, true);
            app.ClearMapButton.Text = {'Clear Map'; ''};
            app.PlotLocal = uibutton(app.GridLayout, 'push');
            app.PlotLocal.ButtonPushedFcn = createCallbackFcn(app, @PlotLocalPushed, true);
            app.PlotLocal.Text = {'Plot Local Data'; ''; '(Acceleration)'; ''};
            app.LoadLocalDataButton = uibutton(app.GridLayout, 'push');
            app.LoadLocalDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLocalDataButtonPushed, true);
            app.LoadLocalDataButton.Text = 'Load Local Data';
            app.ExportCSVFileButton = uibutton(app.GridLayout, 'push');
            app.ExportCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCSVFileButtonPushed, true);
            app.ExportCSVFileButton.Text = 'Export CSV File';
            app.RedSlowingDownLabel = uilabel(app.GridLayout);
            app.RedSlowingDownLabel.Text = 'Red - Slowing Down';
            app.GreenSpeedingUpLabel = uilabel(app.GridLayout);
            app.GreenSpeedingUpLabel.Text = 'Green - Speeding Up';
            app.PurpleSteadySpeedLabel = uilabel(app.GridLayout);
            app.PurpleSteadySpeedLabel.Text = 'Purple - Steady Speed';
            app.LoadThingSpeakDataButton = uibutton(app.GridLayout, 'push');
            app.LoadThingSpeakDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadThingSpeakDataButtonPushed, true);
            app.LoadThingSpeakDataButton.Text = {'Load ThingSpeak '; 'Data'};
            app.DistancemEditFieldLabel = uilabel(app.GridLayout);
            app.DistancemEditFieldLabel.Text = 'Distance (m)';
            app.DistancemEditField = uieditfield(app.GridLayout, 'numeric');
            app.UploadLocal = uibutton(app.GridLayout, 'push');
            app.UploadLocal.ButtonPushedFcn = createCallbackFcn(app, @UploadLocalButtonPushed, true);
            app.UploadLocal.Text = {'Upload Local to '; 'ThingSpeak'};
            app.CheckLoad = uilabel(app.GridLayout);
            app.CheckLoad.Visible = 'off';
            app.CheckLoad.Text = {'CheckLoad'; ''};
            app.PlotCalcLocalTS = uibutton(app.GridLayout, 'push');
            app.PlotCalcLocalTS.ButtonPushedFcn = createCallbackFcn(app, @PlotCalcLocalTSButtonPushed, true);
            app.PlotCalcLocalTS.Text = {'Calculate Distance '; 'Between Local and '; 'TS Data '; '(last position)'};
            app.WriteKey = uieditfield(app.GridLayout, 'text');
            app.WriteKey.Value = 'TS Write API Key';
            app.InsertThingSpeakChannelIDtoReadEditFieldLabel = uilabel(app.GridLayout);
            app.InsertThingSpeakChannelIDtoReadEditFieldLabel.Text = {'Insert ThingSpeak'; 'Channel ID to Read'; ''};
            app.ChannelRead = uieditfield(app.GridLayout, 'numeric');
            app.ChannelRead.ValueDisplayFormat = '%.0f';
            app.ChannelWrite = uieditfield(app.GridLayout, 'text');
            app.ChannelWrite.Value = 'TS Write Channel ID';
            app.BlueLocalPointLabel = uilabel(app.GridLayout);
            app.BlueLocalPointLabel.Text = 'Blue - Local Point';
            app.PinkTSPointLabel = uilabel(app.GridLayout);
            app.PinkTSPointLabel.Text = 'Pink - TS Point';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensor18reach

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end